classdef OpenIoE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpenIoEClientUIFigure         matlab.ui.Figure
        Image                         matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        DataTab                       matlab.ui.container.Tab
        TextArea                      matlab.ui.control.TextArea
        PlotTab                       matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        GaugeTab                      matlab.ui.container.Tab
        EditField_5                   matlab.ui.control.NumericEditField
        EditField_4                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        Gauge2                        matlab.ui.control.SemicircularGauge
        ControlTab                    matlab.ui.container.Tab
        EditField                     matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        Knob                          matlab.ui.control.Knob
        PreferencesTab                matlab.ui.container.Tab
        SaveButton                    matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        PlotConfigTab                 matlab.ui.container.Tab
        AutoCheckBox                  matlab.ui.control.CheckBox
        YLimitEditField               matlab.ui.control.EditField
        YLimitEditFieldLabel          matlab.ui.control.Label
        XLimitEditField_3             matlab.ui.control.EditField
        XLimitEditField_2             matlab.ui.control.EditField
        XLimitEditField               matlab.ui.control.EditField
        XLimitEditFieldLabel          matlab.ui.control.Label
        NoofsamplesperframeEditField  matlab.ui.control.EditField
        NoofsamplesperframeEditFieldLabel  matlab.ui.control.Label
        SamplingTimeEditField         matlab.ui.control.EditField
        YLabelEditField_2Label        matlab.ui.control.Label
        YLabelEditField               matlab.ui.control.EditField
        XLabelEditField_2Label        matlab.ui.control.Label
        XLabelEditField               matlab.ui.control.EditField
        XLabelEditFieldLabel          matlab.ui.control.Label
        TitleEditField                matlab.ui.control.EditField
        TitleEditFieldLabel           matlab.ui.control.Label
        GaugeConfigTab                matlab.ui.container.Tab
        ScaleEditField                matlab.ui.control.EditField
        ScaleEditFieldLabel           matlab.ui.control.Label
        LimitsEditField               matlab.ui.control.EditField
        LimitsEditFieldLabel          matlab.ui.control.Label
        SamplingTimeEditField_2       matlab.ui.control.EditField
        SamplingTimeEditField_2Label  matlab.ui.control.Label
        RGBTripletEditField           matlab.ui.control.EditField
        XLabelEditField_2Label_2      matlab.ui.control.Label
        XLimitEditField_4             matlab.ui.control.EditField
        TitleEditField_2              matlab.ui.control.EditField
        TitleEditField_2Label         matlab.ui.control.Label
        ControlConfigTab              matlab.ui.container.Tab
        LimitsEditField_2             matlab.ui.control.EditField
        LimitsEditField_2Label        matlab.ui.control.Label
        TitleEditField_3              matlab.ui.control.EditField
        TitleEditField_3Label         matlab.ui.control.Label
        XLimitEditField_5             matlab.ui.control.EditField
        IoTConfigTab                  matlab.ui.container.Tab
        APIEndPointEditField          matlab.ui.control.EditField
        APIEndPointEditFieldLabel     matlab.ui.control.Label
        IoTDevicesTab                 matlab.ui.container.Tab
        DevicePinEditField            matlab.ui.control.EditField
        DevicePinEditFieldLabel       matlab.ui.control.Label
        IoTDeviceIDEditField          matlab.ui.control.EditField
        IoTDeviceIDEditFieldLabel     matlab.ui.control.Label
        DeleteButton                  matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        DataTypeDropDown_2            matlab.ui.control.DropDown
        DataTypeDropDown_2Label       matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        APIKeyEditField               matlab.ui.control.EditField
        APIKeyEditFieldLabel          matlab.ui.control.Label
        DownloadTab                   matlab.ui.container.Tab
        DeleteButton_2                matlab.ui.control.Button
        NoofRecordsEditField          matlab.ui.control.NumericEditField
        NoofRecordsEditFieldLabel     matlab.ui.control.Label
        Gauge2_2                      matlab.ui.control.LinearGauge
        UITable2                      matlab.ui.control.Table
        DownloadButton                matlab.ui.control.Button
        StopButton_2                  matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        AboutTab                      matlab.ui.container.Tab
        DeveloperTextArea             matlab.ui.control.TextArea
        DeveloperTextAreaLabel        matlab.ui.control.Label
        AboutTextArea                 matlab.ui.control.TextArea
        AboutTextAreaLabel            matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        StopButton                    matlab.ui.control.Button
        MessageLabel                  matlab.ui.control.Label
        ApplyControlButton            matlab.ui.control.Button
        GaugestartButton              matlab.ui.control.Button
        PlotStartButton               matlab.ui.control.Button
        IoTDeviceIDDropDown           matlab.ui.control.DropDown
        IoTDeviceIDPinTypeLabel       matlab.ui.control.Label
        FetchDataButton               matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        PlatformLamp                  matlab.ui.control.Lamp
        LoginButton                   matlab.ui.control.Button
        OpenIoELabel                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = loaddata(app)
            try 
            load('data.mat')
            app.APIKeyEditField.Value=auth;
            app.LoginButton.Text="Logout";

            app.TitleEditField.Value=config.plot.title;
            app.XLabelEditField.Value=config.plot.xlabel;
            app.YLabelEditField.Value=config.plot.ylabel;
            app.SamplingTimeEditField.Value=string(config.plot.samplingtime);
            app.NoofsamplesperframeEditField.Value=string(config.plot.samplesperframe);
            app.XLimitEditField.Value=string(config.plot.xlim1);
            app.XLimitEditField_2.Value=string(config.plot.xlim2);
            app.YLimitEditField.Value=string(config.plot.ylim1);
            app.XLimitEditField_3.Value=string(config.plot.ylim2);

            app.TitleEditField_2.Value=config.gauge.title;
            app.ScaleEditField.Value=config.gauge.scale;
            app.RGBTripletEditField.Value=config.gauge.rgbtriplet;
            
            app.SamplingTimeEditField_2.Value=string(config.gauge.samplingtime);
            app.LimitsEditField.Value=string(config.gauge.lim1);
            app.XLimitEditField_4.Value=string(config.gauge.lim2);

            app.TitleEditField_3.Value=config.control.title;
            app.LimitsEditField_2.Value=string(config.control.lim1);
            app.XLimitEditField_5.Value=string(config.control.lim2);

            app.APIEndPointEditField.Value=config.iot.endpoint;
            
            app.UITable.Data=config.devices;
          
            
            app.UIAxes.Title.String=app.TitleEditField.Value;
            app.UIAxes.XLabel.String=app.XLabelEditField.Value;
            app.UIAxes.YLabel.String=app.YLabelEditField.Value;   
            app.UIAxes.XLim=[str2double(app.XLimitEditField.Value) str2double(app.XLimitEditField_2.Value)];
            app.UIAxes.YLim=[str2double(app.YLimitEditField.Value) str2double(app.XLimitEditField_3.Value)];

            app.Label.Text=app.TitleEditField_2.Value;
            app.Gauge2.Limits = [str2double(app.LimitsEditField.Value), str2double(app.XLimitEditField_4.Value)];
            app.Gauge2.ScaleColors= str2num(app.RGBTripletEditField.Value);
            app.Gauge2.ScaleColorLimits=str2num(app.ScaleEditField.Value);

            app.Label_2.Text=app.TitleEditField_3.Value;
            app.Knob.Limits=[str2double(app.LimitsEditField_2.Value) str2double(app.XLimitEditField_5.Value)];
           
            if (~isempty(app.UITable.Data))
                app.IoTDeviceIDDropDown.Items=string(app.UITable.Data(:,1));
            end
            catch
                app.MessageLabel.Text='Load Error';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            end
        end
        
        function  tf = CheckOpenIoE(app)
          tf = false;
          try
            address = java.net.InetAddress.getByName('www.google.com');
            tf = true;
          catch
              tf=false;
          end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.OpenIoEClientUIFigure, 'center');
            loaddata(app);
            app.FetchDataButton.Enable="on";
            app.PlotStartButton.Enable="off";
            app.GaugestartButton.Enable="off";
            app.ApplyControlButton.Enable="off";
            app.StopButton.Text='Update';
            app.StopButton.Visible="on";
            app.MessageLabel.Text='Welcome';
            app.MessageLabel.Visible="on";
            pause(2);
            app.MessageLabel.Visible="off";
            tf = CheckOpenIoE(app);
            if tf==true
                app.PlatformLamp.Color=[0.00,1.00,0.00];
            elseif tf==false
                app.PlatformLamp.Color=[1.00,0.00,0.00];
            end
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
           try
               if app.LoginButton.Text=="Login"
                    waitfor(OpenIoELogin)
                    load('data.mat');
                    app.APIKeyEditField.Value=auth;
                    app.LoginButton.Text="Logout";
                elseif app.LoginButton.Text=="Logout"
                    auth="Unauthorized";
                    save('data.mat','auth','-append')
                    app.APIKeyEditField.Value="";
                    app.LoginButton.Text="Login";
                end
           catch
               app.MessageLabel.Text='Login Error';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
           end
            
        end

        % Button pushed function: FetchDataButton
        function FetchDataButtonPushed(app, event)
            apikey=app.APIKeyEditField.Value;
            did=num2str(app.IoTDeviceIDDropDown.Value);
            data=app.UITable.Data;
            endpoint=app.APIEndPointEditField.Value;
            for i=1:size(data(:,1))
                if string(data{i,1})==did
                    pin=data{i,2};
                    datatype=data{i,3};
                end
            end
            options=weboptions('ContentType','text');
            try
                if strcmp(datatype,'Value')    
                    data=webread(strcat(endpoint,"showdevicevalue/",num2str(apikey),'/',num2str(did),"/",num2str(pin)), options);
                elseif datatype=="JSON"
                     data=webread(strcat(endpoint,"showdevicejson/",num2str(apikey),'/',num2str(did),"/",num2str(pin)), options);
                elseif datatype=="XML"
                     data=webread(strcat(endpoint,"showdevicexml/",num2str(apikey),'/',num2str(did),"/",num2str(pin)), options);
                end
                app.TextArea.Value=data;
                app.MessageLabel.Text='Data Fetched';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
               
            catch
                data="Unauthorized";
                app.TextArea.Value=data;
                app.MessageLabel.Text='Data Error';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            end
           
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try 
                config.plot.title=app.TitleEditField.Value;
                config.plot.xlabel=app.XLabelEditField.Value;
                config.plot.ylabel=app.YLabelEditField.Value;
                config.plot.samplingtime=str2double(app.SamplingTimeEditField.Value);
                config.plot.samplesperframe=str2double(app.NoofsamplesperframeEditField.Value);
                config.plot.xlim1=str2double(app.XLimitEditField.Value);
                config.plot.xlim2=str2double(app.XLimitEditField_2.Value);
                config.plot.ylim1=str2double(app.YLimitEditField.Value);
                config.plot.ylim2=str2double(app.XLimitEditField_3.Value);
    
                config.gauge.title=app.TitleEditField_2.Value;
                config.gauge.rgbtriplet=app.RGBTripletEditField.Value;
                config.gauge.scale=app.ScaleEditField.Value;
                config.gauge.samplingtime=str2double(app.SamplingTimeEditField_2.Value);
                config.gauge.lim1=str2double(app.LimitsEditField.Value);
                config.gauge.lim2=str2double(app.XLimitEditField_4.Value);
    
                config.control.title=app.TitleEditField_3.Value;
                config.control.lim1=str2double(app.LimitsEditField_2.Value);
                config.control.lim2=str2double(app.XLimitEditField_5.Value);
    
                config.iot.endpoint=app.APIEndPointEditField.Value;
    
                config.devices=app.UITable.Data;
    
                save('data.mat','config','-append');
                app.MessageLabel.Text='Setting Saved';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
    
                %msgbox('Config data saved successfully','Save','help');
            catch
                app.MessageLabel.Text='Not Saved';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            end
            
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            try
                value = app.AutoCheckBox.Value;
                if value==true
                    app.XLimitEditField.Editable=false;
                    app.XLimitEditField_2.Editable=false;
                    app.XLimitEditField_3.Editable=false;
                    app.YLimitEditField.Editable=false;
                elseif value==false
                    app.XLimitEditField.Editable=true;
                    app.XLimitEditField_2.Editable=true;
                    app.XLimitEditField_3.Editable=true;
                    app.YLimitEditField.Editable=true;
                end
            catch
                app.MessageLabel.Text='Autocheck Error';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            try
                global plotcmd;
                global gaugecmd;
                selectedTab = app.TabGroup.SelectedTab;
                if strcmp(selectedTab.Title,'Data')
                    app.FetchDataButton.Enable="on";
                    app.PlotStartButton.Enable="off";
                    app.GaugestartButton.Enable="off";
                    app.ApplyControlButton.Enable="off";
                    app.StopButton.Text='Update';
                    app.StopButton.Visible="on";
                elseif strcmp(selectedTab.Title,'Plot')
                    app.FetchDataButton.Enable="off";
                    app.PlotStartButton.Enable="on";
                    app.GaugestartButton.Enable="off";
                    app.ApplyControlButton.Enable="off";
                    app.StopButton.Visible="off";
                    app.StopButton.Text='Stop';
                    cla(app.UIAxes);
                elseif strcmp(selectedTab.Title,'Gauge')
                    app.FetchDataButton.Enable="off";
                    app.PlotStartButton.Enable="off";
                    app.GaugestartButton.Enable="on";
                    app.ApplyControlButton.Enable="off";
                    app.StopButton.Text='Stop';
                    app.StopButton.Visible="off";
                elseif strcmp(selectedTab.Title,'Control')
                    app.FetchDataButton.Enable="off";
                    app.PlotStartButton.Enable="off";
                    app.GaugestartButton.Enable="off";
                    app.ApplyControlButton.Enable="on";
                    app.StopButton.Visible="off";
                elseif strcmp(selectedTab.Title,'Preferences')
                    app.FetchDataButton.Enable="off";
                    app.PlotStartButton.Enable="off";
                    app.GaugestartButton.Enable="off";
                    app.ApplyControlButton.Enable="off";
                    app.StopButton.Visible="off";
                elseif strcmp(selectedTab.Title,'About')
                    app.FetchDataButton.Enable="off";
                    app.PlotStartButton.Enable="off";
                    app.GaugestartButton.Enable="off";
                    app.ApplyControlButton.Enable="off";
                    app.StopButton.Visible="off";
                end

                 if plotcmd==1 | gaugecmd==1
                    app.FetchDataButton.Enable="off";
                    app.PlotStartButton.Enable="off";
                    app.GaugestartButton.Enable="off";
                    app.ApplyControlButton.Enable="off";
                 end

                loaddata(app);
            catch
                app.MessageLabel.Text='Tab Error';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            end
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            app.EditField.Value=value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Knob.Value=str2double(value);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            try
                did=str2num(app.IoTDeviceIDEditField.Value);
                pin=str2num(app.DevicePinEditField.Value);
                dt=app.DataTypeDropDown_2.Value;
                load('data.mat');
                config.devices=[config.devices; {did} {pin} {dt}];
                app.UITable.Data=config.devices;
                %save('data.mat','config.devices','-append');
                SaveButtonPushed(app, event);
                app.MessageLabel.Text='Device Added';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            catch
                app.MessageLabel.Text='Add Error';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            end
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            try
                indices = event.Indices;
                data=app.UITable.Data;
                app.IoTDeviceIDEditField.Value=num2str(data{indices(1),1});
                app.DevicePinEditField.Value=num2str(data{indices(1),2});
                app.DataTypeDropDown_2.Value=num2str(data{indices(1),3});
            catch
                app.MessageLabel.Text='Cell Error';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            end

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            try
                data=app.UITable.Data;
                k=1;
                data2=[];
                for i=1:size(data,1)
                    if data{i,1}~=str2num(app.IoTDeviceIDEditField.Value)
                        data2(k,:)=data(i,:);
                        k=k+1;
                    end 
                end
                app.UITable.Data=data2;
                %config.devices=app.UITable.Data;
                %save('data.mat','config.devices','-append');
                SaveButtonPushed(app, event);
                app.MessageLabel.Text='Device Deleted';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            catch
                app.MessageLabel.Text='Delete Error';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            end   
        end

        % Button pushed function: PlotStartButton
        function PlotStartButtonPushed(app, event)
            try
                global plotcmd;
                plotcmd=1;
                app.StopButton.Visible="on";
                app.PlotStartButton.Enable="off";

                apikey=app.APIKeyEditField.Value;
                did=num2str(app.IoTDeviceIDDropDown.Value);
                data=app.UITable.Data;
                endpoint=app.APIEndPointEditField.Value;
                for i=1:size(data(:,1))
                    if string(data{i,1})==did
                        pin=data{i,2};
                        datatype=data{i,3};
                    end
                end
                options=weboptions('ContentType','text');
                y=[];
                app.MessageLabel.Text='Plotting...';
                app.MessageLabel.Visible="on";
                %for i=1:str2double(app.NoofsamplesperframeEditField.Value)
                %while(1)
                xl=str2double(app.XLimitEditField_2.Value);
                yl=str2double(app.XLimitEditField_3.Value);
                for i=1:99999999
                    if plotcmd==0
                        app.GaugestartButton.Enable="off";
                        app.MessageLabel.Text='Plot Stoped';
                        app.MessageLabel.Visible="on";
                        pause(2);
                        app.MessageLabel.Visible="off";
                        break;
                    end
                    if strcmp(datatype,'Value')    
                        data=webread(strcat(endpoint,"showdevicevalue/",num2str(apikey),'/',num2str(did),"/",num2str(pin)), options);
                        d = char(datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss'));
                        app.UITable2.Data=[app.UITable2.Data; {d} {data}];
                    end
                    
                    if i>=xl
                        xl=2*xl;
                        app.UIAxes.XLim=[str2double(app.XLimitEditField.Value) xl];
                    end

                    if str2double(data)>=yl
                        yl=str2double(data)+0.1*str2double(data);
                        app.UIAxes.YLim=[str2double(app.YLimitEditField.Value) yl];
                    end

                    y=[y str2double(data)];
                    plot(app.UIAxes, 1:i, y, 'r','LineWidth',2);


                    drawnow;
                    pause(str2double(app.SamplingTimeEditField.Value));
                end
                pause(2);
                app.MessageLabel.Visible="off";
            catch
                app.MessageLabel.Text='Plot Error';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            end
        end

        % Button pushed function: GaugestartButton
        function GaugestartButtonPushed(app, event)
            try
                global gaugecmd;
                gaugecmd=1;
                app.StopButton.Visible="on";
                app.GaugestartButton.Enable="off";
    
                apikey=app.APIKeyEditField.Value;
                did=num2str(app.IoTDeviceIDDropDown.Value);
                data=app.UITable.Data;
                endpoint=app.APIEndPointEditField.Value;
                for i=1:size(data(:,1))
                    if string(data{i,1})==did
                        pin=data{i,2};
                        datatype=data{i,3};
                    end
                end
                options=weboptions('ContentType','text');

                app.MessageLabel.Text='Gauge running...';
                app.MessageLabel.Visible="on";
                %for i=1:str2double(app.NoofsamplesperframeEditField.Value)
                %while(1)
                for i=1:99999999
                    if gaugecmd==0
                        app.PlotStartButton.Enable="off";
                        app.MessageLabel.Text='Gauge Stoped';
                        app.MessageLabel.Visible="on";
                        pause(2);
                        app.MessageLabel.Visible="off";
                        break;
                    end

                    if strcmp(datatype,'Value')    
                        data=webread(strcat(endpoint,"showdevicevalue/",num2str(apikey),'/',num2str(did),"/",num2str(pin)), options);
                        app.Gauge2.Value=str2double(data);
                        app.EditField_5.Value=str2double(data);
                        d = char(datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss'));
                        app.UITable2.Data=[app.UITable2.Data; {d} {data}];
                    end
                    pause(str2double(app.SamplingTimeEditField_2.Value))
                end
                pause(2);
                app.MessageLabel.Visible="off";
            catch
                app.MessageLabel.Text='Gauge Error';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            end
        end

        % Button pushed function: ApplyControlButton
        function ApplyControlButtonPushed(app, event)
            apikey=app.APIKeyEditField.Value;
            did=num2str(app.IoTDeviceIDDropDown.Value);
            data=app.UITable.Data;
            endpoint=app.APIEndPointEditField.Value;
            value=app.EditField.Value;
            for i=1:size(data(:,1))
                if string(data{i,1})==did
                    pin=data{i,2};
                    datatype=data{i,3};
                end
            end
            options=weboptions('ContentType','text');
            
            try
                if strcmp(datatype,'Value')    
                    data=webread(strcat(endpoint,"updatedevicevalue/",num2str(apikey),'/',num2str(did),"/",num2str(pin),'/',num2str(value)), options);
                    app.MessageLabel.Text='Data Updated';
                    app.MessageLabel.Visible="on";
                    pause(2);
                    app.MessageLabel.Visible="off";
                end    
            catch
                app.MessageLabel.Text='Control Error';
                app.MessageLabel.Visible="on";
                pause(2);
                app.MessageLabel.Visible="off";
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            if strcmp(app.StopButton.Text,'Update')
                apikey=app.APIKeyEditField.Value;
                did=num2str(app.IoTDeviceIDDropDown.Value);
                data=app.UITable.Data;
                endpoint=app.APIEndPointEditField.Value;
                value=char(app.TextArea.Value);
                for i=1:size(data(:,1))
                    if string(data{i,1})==did
                        pin=data{i,2};
                        datatype=data{i,3};
                    end
                end
                options=weboptions('ContentType','text');
                
                try
                    if strcmp(datatype,'Value')    
                        data=webread(strcat(endpoint,"updatedevicevalue/",num2str(apikey),'/',num2str(did),"/",num2str(pin),'/',num2str(value)), options);
                        app.MessageLabel.Text='Data Updated';
                        app.MessageLabel.Visible="on";
                        pause(2);
                        app.MessageLabel.Visible="off";
                    end    
                catch
                    app.MessageLabel.Text='Update Error';
                    app.MessageLabel.Visible="on";
                    pause(2);
                    app.MessageLabel.Visible="off";
                end


            elseif strcmp(app.StopButton.Text,'Stop')
                global plotcmd;
                global gaugecmd;
                plotcmd=0;
                gaugecmd=0;
                app.StopButton.Visible="off";
                app.PlotStartButton.Enable="on";
                app.GaugestartButton.Enable="on";
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                global startdata;
                startdata=0;
                n=app.NoofRecordsEditField.Value;
                apikey=app.APIKeyEditField.Value;
                did=num2str(app.IoTDeviceIDDropDown.Value);
                endpoint=app.APIEndPointEditField.Value;
                options=weboptions('ContentType','text');
                value=char(app.TextArea.Value);
                data=app.UITable.Data;
                for i=1:size(data(:,1))
                    if string(data{i,1})==did
                        pin=data{i,2};
                        datatype=data{i,3};
                    end
                end
                i=1;
                while(startdata==0)
                    try
                        if strcmp(datatype,'Value')                                
                            data=webread(strcat(endpoint,"showdevicevalue/",num2str(apikey),'/',num2str(did),"/",num2str(pin)), options);
                            d = char(datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss'));
                            app.UITable2.Data=[app.UITable2.Data; {d} {data}];
                            app.MessageLabel.Text='Data Download';
                            app.MessageLabel.Visible="on";
                            pause(2);
                            app.MessageLabel.Visible="off";
                        end    
                    catch
                        app.MessageLabel.Text='Update Error';
                        app.MessageLabel.Visible="on";
                        pause(2);
                        app.MessageLabel.Visible="off";
                    end
                    app.Gauge2_2.Value=int8(i/n*100);  
                   
                    pause(1);
                    if (i==n)  
                        startdata=1;  
                    end
                    i=i+1;
                end
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            global startdata;
            startdata=1;
        end

        % Button pushed function: DeleteButton_2
        function DeleteButton_2Pushed(app, event)
            app.UITable2.Data=[];
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            T=[app.UITable2.Data];
            writecell(T,'TimeSeriesData.csv')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OpenIoEClientUIFigure = uifigure('Visible', 'off');
            app.OpenIoEClientUIFigure.Name = 'Open IoE Client';
            app.OpenIoEClientUIFigure.Icon = fullfile(pathToMLAPP, 'logo.png');
            app.Panel = uipanel(app.OpenIoEClientUIFigure);
            app.OpenIoELabel = uilabel(app.Panel);
            app.OpenIoELabel.Text = 'Open IoE';
            app.LoginButton = uibutton(app.Panel, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.PlatformLamp = uilamp(app.Panel);
            app.Panel_2 = uipanel(app.OpenIoEClientUIFigure);
            app.FetchDataButton = uibutton(app.Panel_2, 'push');
            app.FetchDataButton.ButtonPushedFcn = createCallbackFcn(app, @FetchDataButtonPushed, true);
            app.FetchDataButton.Icon = fullfile(pathToMLAPP, 'data.png');
            app.FetchDataButton.Text = 'Fetch Data';
            app.IoTDeviceIDPinTypeLabel = uilabel(app.Panel_2);
            app.IoTDeviceIDPinTypeLabel.Text = 'IoT Device ID ';
            app.IoTDeviceIDDropDown = uidropdown(app.Panel_2);
            app.IoTDeviceIDDropDown.Items = {'Select Device'};
            app.IoTDeviceIDDropDown.Value = 'Select Device';
            app.PlotStartButton = uibutton(app.Panel_2, 'push');
            app.PlotStartButton.ButtonPushedFcn = createCallbackFcn(app, @PlotStartButtonPushed, true);
            app.PlotStartButton.Icon = fullfile(pathToMLAPP, 'plot.png');
            app.PlotStartButton.Text = 'Plot Start';
            app.GaugestartButton = uibutton(app.Panel_2, 'push');
            app.GaugestartButton.ButtonPushedFcn = createCallbackFcn(app, @GaugestartButtonPushed, true);
            app.GaugestartButton.Icon = fullfile(pathToMLAPP, 'gauge.png');
            app.GaugestartButton.Text = 'Gauge start';
            app.ApplyControlButton = uibutton(app.Panel_2, 'push');
            app.ApplyControlButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyControlButtonPushed, true);
            app.ApplyControlButton.Icon = fullfile(pathToMLAPP, 'control.png');
            app.ApplyControlButton.Text = 'Apply Control';
            app.MessageLabel = uilabel(app.Panel_2);
            app.MessageLabel.Visible = 'off';
            app.MessageLabel.Text = 'Message';
            app.StopButton = uibutton(app.Panel_2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.TabGroup = uitabgroup(app.OpenIoEClientUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.TextArea = uitextarea(app.DataTab);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GaugeTab = uitab(app.TabGroup);
            app.GaugeTab.Title = 'Gauge';
            app.Gauge2 = uigauge(app.GaugeTab, 'semicircular');
            app.Label = uilabel(app.GaugeTab);
            app.EditField_2 = uieditfield(app.GaugeTab, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Enable = 'off';
            app.EditField_2.Visible = 'off';
            app.EditField_3 = uieditfield(app.GaugeTab, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Enable = 'off';
            app.EditField_3.Visible = 'off';
            app.EditField_4 = uieditfield(app.GaugeTab, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Enable = 'off';
            app.EditField_4.Visible = 'off';
            app.EditField_5 = uieditfield(app.GaugeTab, 'numeric');
            app.EditField_5.ValueDisplayFormat = '%11.4f';
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.Knob = uiknob(app.ControlTab, 'continuous');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Label_2 = uilabel(app.ControlTab);
            app.EditField = uieditfield(app.ControlTab, 'numeric');
            app.EditField.ValueDisplayFormat = '%11.4f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.PreferencesTab = uitab(app.TabGroup);
            app.PreferencesTab.Title = 'Preferences';
            app.APIKeyEditFieldLabel = uilabel(app.PreferencesTab);
            app.APIKeyEditFieldLabel.Text = 'API Key';
            app.APIKeyEditField = uieditfield(app.PreferencesTab, 'text');
            app.TabGroup2 = uitabgroup(app.PreferencesTab);
            app.PlotConfigTab = uitab(app.TabGroup2);
            app.PlotConfigTab.Title = 'Plot Config';
            app.TitleEditFieldLabel = uilabel(app.PlotConfigTab);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.PlotConfigTab, 'text');
            app.TitleEditField.Value = 'Title of Plot';
            app.XLabelEditFieldLabel = uilabel(app.PlotConfigTab);
            app.XLabelEditFieldLabel.Text = 'X Label';
            app.XLabelEditField = uieditfield(app.PlotConfigTab, 'text');
            app.XLabelEditField.Value = 'X-axis label';
            app.XLabelEditField_2Label = uilabel(app.PlotConfigTab);
            app.XLabelEditField_2Label.Text = 'Y Label';
            app.YLabelEditField = uieditfield(app.PlotConfigTab, 'text');
            app.YLabelEditField.Value = 'Y-axis label';
            app.YLabelEditField_2Label = uilabel(app.PlotConfigTab);
            app.YLabelEditField_2Label.Text = 'Sampling Time';
            app.SamplingTimeEditField = uieditfield(app.PlotConfigTab, 'text');
            app.SamplingTimeEditField.Value = '1';
            app.NoofsamplesperframeEditFieldLabel = uilabel(app.PlotConfigTab);
            app.NoofsamplesperframeEditFieldLabel.Text = 'No of samples per frame';
            app.NoofsamplesperframeEditField = uieditfield(app.PlotConfigTab, 'text');
            app.NoofsamplesperframeEditField.Value = '100';
            app.XLimitEditFieldLabel = uilabel(app.PlotConfigTab);
            app.XLimitEditFieldLabel.Text = 'X Limit';
            app.XLimitEditField = uieditfield(app.PlotConfigTab, 'text');
            app.XLimitEditField.Value = '0';
            app.XLimitEditField_2 = uieditfield(app.PlotConfigTab, 'text');
            app.XLimitEditField_2.Value = '100';
            app.XLimitEditField_3 = uieditfield(app.PlotConfigTab, 'text');
            app.XLimitEditField_3.Value = '100';
            app.YLimitEditFieldLabel = uilabel(app.PlotConfigTab);
            app.YLimitEditFieldLabel.Text = 'Y Limit';
            app.YLimitEditField = uieditfield(app.PlotConfigTab, 'text');
            app.YLimitEditField.Value = '0';
            app.AutoCheckBox = uicheckbox(app.PlotConfigTab);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.GaugeConfigTab = uitab(app.TabGroup2);
            app.GaugeConfigTab.Title = 'Gauge Config';
            app.TitleEditField_2Label = uilabel(app.GaugeConfigTab);
            app.TitleEditField_2Label.Text = 'Title';
            app.TitleEditField_2 = uieditfield(app.GaugeConfigTab, 'text');
            app.TitleEditField_2.Value = 'Title of Gauge';
            app.XLimitEditField_4 = uieditfield(app.GaugeConfigTab, 'text');
            app.XLimitEditField_4.Value = '100';
            app.XLabelEditField_2Label_2 = uilabel(app.GaugeConfigTab);
            app.XLabelEditField_2Label_2.Text = 'RGB Triplet';
            app.RGBTripletEditField = uieditfield(app.GaugeConfigTab, 'text');
            app.RGBTripletEditField.Value = '[0 1 0; 1 1 0;1 0 0]';
            app.SamplingTimeEditField_2Label = uilabel(app.GaugeConfigTab);
            app.SamplingTimeEditField_2Label.Text = 'Sampling Time';
            app.SamplingTimeEditField_2 = uieditfield(app.GaugeConfigTab, 'text');
            app.SamplingTimeEditField_2.Value = '1';
            app.LimitsEditFieldLabel = uilabel(app.GaugeConfigTab);
            app.LimitsEditFieldLabel.Text = 'Limits';
            app.LimitsEditField = uieditfield(app.GaugeConfigTab, 'text');
            app.LimitsEditField.Value = '0';
            app.ScaleEditFieldLabel = uilabel(app.GaugeConfigTab);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.GaugeConfigTab, 'text');
            app.ScaleEditField.Value = '[0 40; 40 70;70 100]';
            app.ControlConfigTab = uitab(app.TabGroup2);
            app.ControlConfigTab.Title = 'Control Config';
            app.XLimitEditField_5 = uieditfield(app.ControlConfigTab, 'text');
            app.XLimitEditField_5.Value = '10';
            app.TitleEditField_3Label = uilabel(app.ControlConfigTab);
            app.TitleEditField_3Label.Text = 'Title';
            app.TitleEditField_3 = uieditfield(app.ControlConfigTab, 'text');
            app.TitleEditField_3.Value = 'Title of control';
            app.LimitsEditField_2Label = uilabel(app.ControlConfigTab);
            app.LimitsEditField_2Label.Text = 'Limits';
            app.LimitsEditField_2 = uieditfield(app.ControlConfigTab, 'text');
            app.LimitsEditField_2.Value = '1';
            app.IoTConfigTab = uitab(app.TabGroup2);
            app.IoTConfigTab.Title = 'IoT Config';
            app.APIEndPointEditFieldLabel = uilabel(app.IoTConfigTab);
            app.APIEndPointEditFieldLabel.Text = 'API End Point';
            app.APIEndPointEditField = uieditfield(app.IoTConfigTab, 'text');
            app.IoTDevicesTab = uitab(app.TabGroup2);
            app.IoTDevicesTab.Title = 'IoT Devices';
            app.UITable = uitable(app.IoTDevicesTab);
            app.UITable.ColumnName = {'Device ID'; 'Device Pin'; 'Device Data Type'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DataTypeDropDown_2Label = uilabel(app.IoTDevicesTab);
            app.DataTypeDropDown_2Label.Text = 'Data Type';
            app.DataTypeDropDown_2 = uidropdown(app.IoTDevicesTab);
            app.DataTypeDropDown_2.Items = {'Value', 'JSON', 'XML'};
            app.DataTypeDropDown_2.Value = 'Value';
            app.AddButton = uibutton(app.IoTDevicesTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Icon = fullfile(pathToMLAPP, 'add.png');
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.IoTDevicesTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Icon = fullfile(pathToMLAPP, 'delete.png');
            app.DeleteButton.Text = 'Delete';
            app.IoTDeviceIDEditFieldLabel = uilabel(app.IoTDevicesTab);
            app.IoTDeviceIDEditFieldLabel.Text = 'IoT Device ID';
            app.IoTDeviceIDEditField = uieditfield(app.IoTDevicesTab, 'text');
            app.DevicePinEditFieldLabel = uilabel(app.IoTDevicesTab);
            app.DevicePinEditFieldLabel.Text = 'Device Pin';
            app.DevicePinEditField = uieditfield(app.IoTDevicesTab, 'text');
            app.SaveButton = uibutton(app.PreferencesTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveButton.Text = 'Save';
            app.DownloadTab = uitab(app.TabGroup);
            app.DownloadTab.Title = 'Download';
            app.StartButton = uibutton(app.DownloadTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton_2 = uibutton(app.DownloadTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.DownloadButton = uibutton(app.DownloadTab, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.UITable2 = uitable(app.DownloadTab);
            app.UITable2.ColumnName = {'Time Stamp'; 'Data'};
            app.UITable2.RowName = {};
            app.Gauge2_2 = uigauge(app.DownloadTab, 'linear');
            app.NoofRecordsEditFieldLabel = uilabel(app.DownloadTab);
            app.NoofRecordsEditFieldLabel.Text = 'No of Records';
            app.NoofRecordsEditField = uieditfield(app.DownloadTab, 'numeric');
            app.NoofRecordsEditField.Value = 5;
            app.DeleteButton_2 = uibutton(app.DownloadTab, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_2Pushed, true);
            app.DeleteButton_2.Text = 'Delete';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AboutTextAreaLabel = uilabel(app.AboutTab);
            app.AboutTextAreaLabel.Text = 'About';
            app.AboutTextArea = uitextarea(app.AboutTab);
            app.AboutTextArea.Editable = 'off';
            app.AboutTextArea.Value = {'Open IoE Matlab Client:'; '-------------------------------'; 'This application is to manage the IoT devices created in Open IoE plotform.'; ''; ''; 'https://openioe.in'; ''; '1. Register and login to OpenIoE platform'; '2. Create IoT device'; '3. Add IoT devices under Preferences>>IoT Devices'; ''};
            app.DeveloperTextAreaLabel = uilabel(app.AboutTab);
            app.DeveloperTextAreaLabel.Text = 'Developer';
            app.DeveloperTextArea = uitextarea(app.AboutTab);
            app.DeveloperTextArea.Editable = 'off';
            app.DeveloperTextArea.Value = {'Dr. Venkataswamy R'; ''; 'https://venkataswamy.in'; ''; 'Email: venkataswamy.r@gmail.com'};
            app.Image = uiimage(app.OpenIoEClientUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.svg');
            % Show the figure after all components are created
            app.OpenIoEClientUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenIoE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OpenIoEClientUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpenIoEClientUIFigure)
        end
    end
end