classdef OpenIoELogin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        Panel_2                        matlab.ui.container.Panel
        PasswordEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        UserNameEmailIDEditField       matlab.ui.control.EditField
        UserNameEmailIDEditFieldLabel  matlab.ui.control.Label
        LoginButton_2                  matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        LoginLabel                     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: LoginButton_2
        function LoginButton_2Pushed(app, event)
            un=app.UserNameEmailIDEditField.Value;
            pw=app.PasswordEditField.Value;
            options=weboptions('ContentType','text');
            try
                auth=webread(strcat("http://apps.gnanodaya.org/openioe/api/showapikey/",un,"/",pw), options);
            catch
                auth="Unauthorized";
            end
            if auth=="Unauthorized"
                errordlg('User name or Password does not exit','Unauthorized');
            else
                save('data.mat','auth','-append')
                close(app.UIFigure);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.LoginLabel = uilabel(app.Panel);
            app.LoginLabel.Text = 'Login';
            app.Panel_2 = uipanel(app.UIFigure);
            app.CancelButton = uibutton(app.Panel_2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.LoginButton_2 = uibutton(app.Panel_2, 'push');
            app.LoginButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoginButton_2Pushed, true);
            app.LoginButton_2.Text = 'Login';
            app.UserNameEmailIDEditFieldLabel = uilabel(app.Panel_2);
            app.UserNameEmailIDEditFieldLabel.Text = 'User Name/Email ID';
            app.UserNameEmailIDEditField = uieditfield(app.Panel_2, 'text');
            app.PasswordEditFieldLabel = uilabel(app.Panel_2);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.Panel_2, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.svg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenIoELogin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end